classdef Conversor_Temperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Image3    matlab.ui.control.Image
        Image2    matlab.ui.control.Image
        label3    matlab.ui.control.Label
        label2    matlab.ui.control.Label
        label1    matlab.ui.control.Label
        labelK    matlab.ui.control.Label
        labelF    matlab.ui.control.Label
        labelC    matlab.ui.control.Label
        botaoK    matlab.ui.control.CheckBox
        botaoF    matlab.ui.control.CheckBox
        botaoC    matlab.ui.control.CheckBox
        textoK    matlab.ui.control.NumericEditField
        textoF    matlab.ui.control.NumericEditField
        textoC    matlab.ui.control.NumericEditField
        Image     matlab.ui.control.Image
        ConversordeUnidadesdeTemperaturaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: botaoC
        function botaoCValueChanged(app, event)
            if app.botaoC.Value
                app.botaoF.Value = false;
                app.botaoK.Value = false;

                app.textoC.Enable = "on";
                app.textoC.Editable = "on";
                app.textoF.Enable = "off";
                app.textoF.Editable = "off";
                app.textoK.Enable = "off";
                app.textoK.Editable = "off";
                
                app.textoF.Value = 0;
                app.textoK.Value = 0;

                app.labelC.Text = num2str(app.textoC.Value);
                c = app.textoC.Value;
                f = (c * 1.8) + 32;
                k = c + 273.15;
                app.labelF.Text = num2str(f);
                app.labelK.Text = num2str(k);
            end
        end

        % Value changed function: botaoF
        function botaoFValueChanged(app, event)
            if app.botaoF.Value
                app.botaoC.Value = false;
                app.botaoK.Value = false;

                app.textoF.Enable = "on";
                app.textoF.Editable = "on";
                app.textoC.Enable = "off";
                app.textoC.Editable = "off";
                app.textoK.Enable = "off";
                app.textoK.Editable = "off";

                app.textoC.Value = 0;
                app.textoK.Value = 0;

                app.labelF.Text = num2str(app.textoF.Value);
                f = app.textoF.Value;
                c = (f - 32) / 1.8;
                k = (f - 32)*(5/9) + 273.15;
                app.labelC.Text = num2str(c);
                app.labelK.Text = num2str(k);
            end
        end

        % Value changed function: botaoK
        function botaoKValueChanged(app, event)
            if app.botaoK.Value
                app.botaoC.Value = false;
                app.botaoF.Value = false;
                
                app.textoK.Enable = "on";
                app.textoK.Editable = "on";
                app.textoC.Enable = "off";
                app.textoC.Editable = "off";
                app.textoF.Enable = "off";
                app.textoF.Editable = "off";

                app.textoC.Value = 0;
                app.textoF.Value = 0;

                app.labelK.Text = num2str(app.textoK.Value);
                k = app.textoK.Value;
                c = k - 273.15;
                f = (k - 273.15)*(1.8) + 32;
                app.labelC.Text = num2str(c);
                app.labelF.Text = num2str(f);
            end
            
        end

        % Value changed function: textoF
        function textoFValueChanged(app, event)

        end

        % Value changed function: textoK
        function textoKValueChanged(app, event)

        end

        % Value changed function: textoC
        function textoCValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConversordeUnidadesdeTemperaturaLabel = uilabel(app.UIFigure);
            app.ConversordeUnidadesdeTemperaturaLabel.Text = 'Conversor de Unidades de Temperatura';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'temperatura.png');
            app.textoC = uieditfield(app.UIFigure, 'numeric');
            app.textoC.ValueChangedFcn = createCallbackFcn(app, @textoCValueChanged, true);
            app.textoF = uieditfield(app.UIFigure, 'numeric');
            app.textoF.ValueChangedFcn = createCallbackFcn(app, @textoFValueChanged, true);
            app.textoF.Editable = 'off';
            app.textoF.Enable = 'off';
            app.textoK = uieditfield(app.UIFigure, 'numeric');
            app.textoK.ValueChangedFcn = createCallbackFcn(app, @textoKValueChanged, true);
            app.textoK.Editable = 'off';
            app.textoK.Enable = 'off';
            app.botaoC = uicheckbox(app.UIFigure);
            app.botaoC.ValueChangedFcn = createCallbackFcn(app, @botaoCValueChanged, true);
            app.botaoC.Text = '';
            app.botaoF = uicheckbox(app.UIFigure);
            app.botaoF.ValueChangedFcn = createCallbackFcn(app, @botaoFValueChanged, true);
            app.botaoF.Text = '';
            app.botaoK = uicheckbox(app.UIFigure);
            app.botaoK.ValueChangedFcn = createCallbackFcn(app, @botaoKValueChanged, true);
            app.botaoK.Text = '';
            app.labelC = uilabel(app.UIFigure);
            app.labelC.Text = '---';
            app.labelF = uilabel(app.UIFigure);
            app.labelF.Text = '---';
            app.labelK = uilabel(app.UIFigure);
            app.labelK.Text = '---';
            app.label1 = uilabel(app.UIFigure);
            app.label1.Text = 'ºC';
            app.label2 = uilabel(app.UIFigure);
            app.label2.Text = 'ºF';
            app.label3 = uilabel(app.UIFigure);
            app.label3.Text = 'K';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'tabela_conversão (2).png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'tabela_conversão (1).png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Obs: desabilitar e habilitar novamente o botão para calcular temperaturas com novos valores.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Conversor_Temperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end